classdef VisAnaGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ReadTab                matlab.ui.container.Tab
        SearchButton           matlab.ui.control.Button
        runlogTextAreaLabel    matlab.ui.control.Label
        runlogTextArea         matlab.ui.control.TextArea
        TabPlot                matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        Label                  matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        yminEditFieldLabel     matlab.ui.control.Label
        yminEditField          matlab.ui.control.NumericEditField
        xminEditFieldLabel     matlab.ui.control.Label
        xminEditField          matlab.ui.control.NumericEditField
        xmaxEditField_2Label   matlab.ui.control.Label
        xmaxEditField          matlab.ui.control.NumericEditField
        ymaxEditFieldLabel     matlab.ui.control.Label
        ymaxEditField          matlab.ui.control.NumericEditField
        Plottype               matlab.ui.container.ButtonGroup
        Plottype1D             matlab.ui.control.ToggleButton
        Plottype2D             matlab.ui.control.ToggleButton
        Plottype3D             matlab.ui.control.ToggleButton
        autoredrawCheckBox     matlab.ui.control.CheckBox
        PlotButton             matlab.ui.control.Button
        userrangeCheckBox      matlab.ui.control.CheckBox
        plotmodeDropDownLabel  matlab.ui.control.Label
        plotmodeDropDown       matlab.ui.control.DropDown
        plotvarEditFieldLabel  matlab.ui.control.Label
        plotvarEditField       matlab.ui.control.EditField
        TabData                matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
    end

    % The app displays the data by using the scatter plot and histogram.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = private)
        % Declare properties of the file class.
        filename
        filehead
        Data
        func
        plotmode
        plotrange
        plotinterval % Description
    end
    
    methods (Access = private)
        
        function NBins = test(app,data)

        end
        
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Now we start')
            refreshplot(app)
        end

        % Value changed function: UITable
        function refreshplot(app, event)
            %Colors = app.SelectedColors;

            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
                           
        end

        % Callback function: SearchButton, runlogTextArea
        function ReadData(app, event)
           [app.filename, pathname, filterindex] = uigetfile( ...
              {  '*.log;*.dat;*.out;*.outs','Output Files (*.log,*.dat,*.out,*.outs)';...
              '*.log;*.dat', 'ASCII files (*.log,*.dat)';...
              '*.out;*.outs', 'Binary files (*.out,*.outs)';...
              '*.*','All Files (*.*)'}, ...
              'Pick a file', ...
              'MultiSelect', 'on');


           if app.filename~=0
              %diary commandwindowOutput.log
              %[app.filehead,app.Data] = read_data(strcat(pathname,app.filename));
              %diary off
              %info = string(fileread('commandwindowOutput.log'));
              [T,app.filehead,app.Data] = evalc('read_data(strcat(pathname,app.filename))');
           else
              uialert(app.UIFigure,'File not found','Invalid File');
           end
           
           % Here I want to throw feedback info back to the UI window textbox
           app.runlogTextArea.Value = T;

        end

        % Value changed function: plotmodeDropDown
        function plotmodeChanged(app, event)
            app.plotmode = app.plotmodeDropDown.Value;
            
        end

        % Callback function: userrangeCheckBox, xmaxEditField, 
        % xminEditField, ymaxEditField, yminEditField
        function plotrangeChanged(app, event)
           if(~app.userrangeCheckBox.Value)
              app.plotrange(1) = app.xminEditField.Value;
              app.plotrange(2) = app.xmaxEditField.Value;
              app.plotrange(3) = app.yminEditField.Value;
              app.plotrange(4) = app.ymaxEditField.Value;
           end
        end

        % Value changed function: plotvarEditField
        function plotvarChanged(app, event)
            app.func = app.plotvarEditField.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           if app.Plottype1D.Value
              T = evalc(['plot_log_data(app.Data,app.filehead,app.func,' ...
                  ' ''plotmode'',''line'');']);
           elseif app.Plottype2D.Value
              plot_data(app.Data.file1,app.filehead,app.func,...
                 'plotmode',app.plotmode,...
                 'plotrange',app.plotrange,...
                 'plotinterval',app.plotinterval);
           elseif app.Plottype3D.Value
              uialert(app.UIFigure,'3D visualization not yet implemented',...
                  'Invalid Plot type');
           end
           
           % Send information to output display window
           app.runlogTextArea.Value = T;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'VisAna';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReadTab = uitab(app.TabGroup);
            app.ReadTab.Title = 'Read';
            app.SearchButton = uibutton(app.ReadTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @ReadData, true);
            app.SearchButton.Text = 'Search';
            app.runlogTextAreaLabel = uilabel(app.ReadTab);
            app.runlogTextAreaLabel.Text = 'runlog';
            app.runlogTextArea = uitextarea(app.ReadTab);
            app.runlogTextArea.ValueChangedFcn = createCallbackFcn(app, @ReadData, true);
            app.TabPlot = uitab(app.TabGroup);
            app.TabPlot.Title = 'Plot';
            app.UIAxes = uiaxes(app.TabPlot);
            xlabel(app.UIAxes, 'Weight')
            ylabel(app.UIAxes, 'Diastolic')
            app.Label = uilabel(app.TabPlot);
            app.Label.Text = 'Data Visualization';
            app.Panel = uipanel(app.TabPlot);
            app.yminEditFieldLabel = uilabel(app.Panel);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.Panel, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.xminEditFieldLabel = uilabel(app.Panel);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.Panel, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.xmaxEditField_2Label = uilabel(app.Panel);
            app.xmaxEditField_2Label.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.Panel, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.ymaxEditFieldLabel = uilabel(app.Panel);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.Panel, 'numeric');
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.Plottype = uibuttongroup(app.Panel);
            app.Plottype.Title = 'Plot type';
            app.Plottype1D = uitogglebutton(app.Plottype);
            app.Plottype1D.Text = '1D';
            app.Plottype2D = uitogglebutton(app.Plottype);
            app.Plottype2D.Text = '2D';
            app.Plottype2D.Value = true;
            app.Plottype3D = uitogglebutton(app.Plottype);
            app.Plottype3D.Text = '3D';
            app.autoredrawCheckBox = uicheckbox(app.Panel);
            app.autoredrawCheckBox.Text = 'auto redraw';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.userrangeCheckBox = uicheckbox(app.Panel);
            app.userrangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.userrangeCheckBox.Text = 'user range';
            app.plotmodeDropDownLabel = uilabel(app.TabPlot);
            app.plotmodeDropDownLabel.Text = 'plotmode';
            app.plotmodeDropDown = uidropdown(app.TabPlot);
            app.plotmodeDropDown.Items = {'cont', 'contbar', 'contbarlog', 'mesh', 'meshbar', 'meshbarlog', 'contbar streamover', 'grid', 'Option 4'};
            app.plotmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @plotmodeChanged, true);
            app.plotmodeDropDown.Value = 'cont';
            app.plotvarEditFieldLabel = uilabel(app.TabPlot);
            app.plotvarEditFieldLabel.Text = 'plotvar';
            app.plotvarEditField = uieditfield(app.TabPlot, 'text');
            app.plotvarEditField.ValueChangedFcn = createCallbackFcn(app, @plotvarChanged, true);
            app.TabData = uitab(app.TabGroup);
            app.TabData.Title = 'Data';
            app.UITable = uitable(app.TabData);
            app.UITable.ColumnName = {'Last Name'; 'Gender'; 'Smoker'; 'Age'; 'Height'; 'Weight'; 'Diastolic'; 'Systolic'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @refreshplot, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = VisAnaGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end